classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EjemploUIFigure        matlab.ui.Figure
        CargarButton           matlab.ui.control.Button
        MensajeEditField       matlab.ui.control.EditField
        MensajeEditFieldLabel  matlab.ui.control.Label
        Saludo                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            if(strcmp(app.CargarButton.Text,'Cargar'))
                app.Saludo.Text=app.MensajeEditField.Value; 
                app.Saludo.FontSize=32;
                app.CargarButton.Position=[27,81,100,23];
                app.Saludo.Visible="on";
                app.CargarButton.Text='Regresar';
                app.MensajeEditField.Visible="off";
                app.MensajeEditFieldLabel.Visible="off"
                app.MensajeEditField.Value='';

            else
                app.CargarButton.Position=[27,207,100,23];
                app.Saludo.Visible="off";
                app.CargarButton.Text='Cargar';
                app.MensajeEditField.Visible="on";
                app.MensajeEditFieldLabel.Visible="on"


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EjemploUIFigure = uifigure('Visible', 'off');
            app.EjemploUIFigure.Name = 'Ejemplo';
            app.Saludo = uilabel(app.EjemploUIFigure);
            app.Saludo.Visible = 'off';
            app.Saludo.Text = 'Hola!';
            app.MensajeEditFieldLabel = uilabel(app.EjemploUIFigure);
            app.MensajeEditFieldLabel.Text = 'Mensaje';
            app.MensajeEditField = uieditfield(app.EjemploUIFigure, 'text');
            app.CargarButton = uibutton(app.EjemploUIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            % Show the figure after all components are created
            app.EjemploUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EjemploUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EjemploUIFigure)
        end
    end
end