classdef ProgA_Ej < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProgAUIFigure           matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        IngresarMenu            matlab.ui.container.Menu
        MostrartablaMenu        matlab.ui.container.Menu
        EliminarMenu            matlab.ui.container.Menu
        EliminarButton          matlab.ui.control.Button
        NombreEliminar          matlab.ui.control.EditField
        NombreEditField_2Label  matlab.ui.control.Label
        Planilla                matlab.ui.control.Table
        IngresarButton          matlab.ui.control.Button
        Nota2EditField          matlab.ui.control.NumericEditField
        Nota2EditFieldLabel     matlab.ui.control.Label
        Nota1EditField          matlab.ui.control.NumericEditField
        Nota1EditFieldLabel     matlab.ui.control.Label
        NombreEditField         matlab.ui.control.EditField
        NombreEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        datos % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IngresarButton
        function IngresarButtonPushed(app, event)
            app.Planilla.Data=[app.Planilla.Data;[{app.NombreEditField.Value},app.Nota1EditField.Value,app.Nota2EditField.Value,(app.Nota2EditField.Value+app.Nota1EditField.Value)/2]];
            app.datos=app.Planilla.Data;
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            nombre=app.NombreEliminar.Value;

            [filas,column]=size(app.datos);

            for i=1:filas
                if(strcmp(nombre,app.datos{i,1}))
                    app.datos(i,:)=[];
                    app.Planilla.Data=app.datos;
                    break
                end    
            end    


        end

        % Menu selected function: IngresarMenu
        function IngresarMenuSelected(app, event)

            %Ingresar
            app.NombreEditField.Visible = 'on';
            app.NombreEditFieldLabel.Visible = 'on';            
            app.Nota1EditFieldLabel.Visible = 'on';
            app.Nota1EditField.Visible = 'on';
            app.Nota2EditFieldLabel.Visible = 'on';
            app.Nota2EditField.Visible = 'on';
            app.IngresarButton.Visible = 'on';
            
            %Mostrar
            app.Planilla.Visible = 'off';
            

            %Eliminar
            app.NombreEliminar.Visible = 'off';
            app.NombreEditField_2Label.Visible = 'off';
            app.EliminarButton.Visible = 'off';


            % app.NombreEditField.Visible = 'off';
            % app.NombreEditFieldLabel.Visible = 'off';            
            % app.Nota1EditFieldLabel.Visible = 'off';
            % app.Nota1EditField.Visible = 'off';
            % app.Nota2EditFieldLabel.Visible = 'off';
            % app.Nota2EditField.Visible = 'off';
            % app.IngresarButton.Visible = 'off';
            % 
            % app.Planilla.Visible = 'off';
            % 
            % app.NombreEliminar.Visible = 'off';
            % app.NombreEditField_2Label.Visible = 'off';
            % app.EliminarButton.Visible = 'off';


        end

        % Menu selected function: MostrartablaMenu
        function MostrartablaMenuSelected(app, event)
            app.NombreEditField.Visible = 'off';
            %app.NombreEditField.Position = [141 404 100 22];
            app.NombreEditFieldLabel.Visible = 'off';
            %app.NombreEditFieldLabel.Position = [71 404 48 22];
            app.Nota1EditFieldLabel.Visible = 'off';
            app.Nota1EditField.Visible = 'off';
            app.Nota2EditFieldLabel.Visible = 'off';
            app.Nota2EditField.Visible = 'off';
            app.IngresarButton.Visible = 'off';

            app.Planilla.Visible = 'on';

            app.NombreEliminar.Visible = 'off';
            app.NombreEditField_2Label.Visible = 'off';
            app.EliminarButton.Visible = 'off';
        end

        % Menu selected function: EliminarMenu
        function EliminarMenuSelected(app, event)
            app.NombreEditField.Visible = 'off';
            app.NombreEditFieldLabel.Visible = 'off';            
            app.Nota1EditFieldLabel.Visible = 'off';
            app.Nota1EditField.Visible = 'off';
            app.Nota2EditFieldLabel.Visible = 'off';
            app.Nota2EditField.Visible = 'off';
            app.IngresarButton.Visible = 'off';

            app.Planilla.Visible = 'off';

            app.NombreEliminar.Visible = 'on';
            %app.NombreEliminar.Position= [141 404 105 26];
            app.NombreEditField_2Label.Visible = 'on';
            app.EliminarButton.Visible = 'on';
            %app.EliminarButton.Position= [346 404 101 27];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProgAUIFigure = uifigure('Visible', 'off');
            app.ProgAUIFigure.Name = 'ProgA';
            app.Menu = uimenu(app.ProgAUIFigure);
            app.Menu.Text = 'Menu';
            app.IngresarMenu = uimenu(app.Menu);
            app.IngresarMenu.MenuSelectedFcn = createCallbackFcn(app, @IngresarMenuSelected, true);
            app.IngresarMenu.Text = 'Ingresar';
            app.MostrartablaMenu = uimenu(app.Menu);
            app.MostrartablaMenu.MenuSelectedFcn = createCallbackFcn(app, @MostrartablaMenuSelected, true);
            app.MostrartablaMenu.Text = 'Mostrar tabla';
            app.EliminarMenu = uimenu(app.Menu);
            app.EliminarMenu.MenuSelectedFcn = createCallbackFcn(app, @EliminarMenuSelected, true);
            app.EliminarMenu.Text = 'Eliminar';
            app.NombreEditFieldLabel = uilabel(app.ProgAUIFigure);
            app.NombreEditFieldLabel.Visible = 'off';
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.ProgAUIFigure, 'text');
            app.NombreEditField.Visible = 'off';
            app.Nota1EditFieldLabel = uilabel(app.ProgAUIFigure);
            app.Nota1EditFieldLabel.Visible = 'off';
            app.Nota1EditFieldLabel.Text = 'Nota 1';
            app.Nota1EditField = uieditfield(app.ProgAUIFigure, 'numeric');
            app.Nota1EditField.Visible = 'off';
            app.Nota2EditFieldLabel = uilabel(app.ProgAUIFigure);
            app.Nota2EditFieldLabel.Visible = 'off';
            app.Nota2EditFieldLabel.Text = 'Nota 2';
            app.Nota2EditField = uieditfield(app.ProgAUIFigure, 'numeric');
            app.Nota2EditField.Visible = 'off';
            app.IngresarButton = uibutton(app.ProgAUIFigure, 'push');
            app.IngresarButton.ButtonPushedFcn = createCallbackFcn(app, @IngresarButtonPushed, true);
            app.IngresarButton.Visible = 'off';
            app.IngresarButton.Text = 'Ingresar';
            app.Planilla = uitable(app.ProgAUIFigure);
            app.Planilla.ColumnName = {'Estudiantes'; 'Nota 1'; 'Nota 2'; 'Nota Final'};
            app.Planilla.RowName = {};
            app.Planilla.Visible = 'off';
            app.NombreEditField_2Label = uilabel(app.ProgAUIFigure);
            app.NombreEditField_2Label.Visible = 'off';
            app.NombreEditField_2Label.Text = 'Nombre';
            app.NombreEliminar = uieditfield(app.ProgAUIFigure, 'text');
            app.NombreEliminar.Visible = 'off';
            app.EliminarButton = uibutton(app.ProgAUIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Visible = 'off';
            app.EliminarButton.Text = 'Eliminar';
            % Show the figure after all components are created
            app.ProgAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProgA_Ej

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProgAUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProgAUIFigure)
        end
    end
end